classdef InteractivePhaseCorr < matlab.apps.AppBase
    %INTERACTIVEPHASECORR To perform phase correction on nmr spectrum
    %   InterativePhaseCorr(faxis, spec, phase)
    %   
    %   faxis: 1D frequency axis in Hz
    %   spec: 1D Spectrum same length as freqaxid
    %   phase: [Optional] initial phase correction terms. [0,0] default 
    %   
    %   
    %   example
    %   InterativePhaseCorr(faxis,spec1(:,1,end))
    %   InterativePhaseCorr(faxis,spec1(:,1,end),[3 0.0075])

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        repetitionsSpinner       matlab.ui.control.Spinner
        repetitionsSpinnerLabel  matlab.ui.control.Label
        PlotBothFlag             matlab.ui.control.Switch
        Phase1sSlider            matlab.ui.control.Slider
        Phase1Label              matlab.ui.control.Label
        Phase1Spinner            matlab.ui.control.Spinner
        Phase0Spinner            matlab.ui.control.Spinner
        Phase0radSlider          matlab.ui.control.Slider
        Phase0radSliderLabel     matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        faxis
        spec
        spec_corr
        spec_all
        phase_all
        cRep
    end

    
    methods (Access = private)
        
        function  plotSpec(app)
            cla(app.UIAxes);
      
            plot(app.UIAxes,app.faxis,real(app.spec_corr),'LineWidth',2)
            xlabel(app.UIAxes,'Frequency [Hz]')
            legend(app.UIAxes,{'Corr'})

            if(app.PlotBothFlag.Value)
            hold(app.UIAxes,"on");
            plot(app.UIAxes,app.faxis,abs(app.spec),'LineWidth',2,'DisplayName','abs')
            legend(app.UIAxes,{'Corr','Orig'})
            end
            grid(app.UIAxes,"minor"),grid(app.UIAxes,"on"),

        end
        
        function performPhaseCorr(app)
            phase0=app.Phase0Spinner.Value; %rad
            phase1=app.Phase1Spinner.Value*1e-6; % us -> s
            app.spec_corr=app.spec(:).*exp(-1i*(phase0+2*pi*app.faxis(:).*phase1));
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, faxis, spec_all, phase)
            if(~exist('phase','var'))
                phase=[0,0];
            end
            app.faxis=faxis;
            app.cRep=1;
            app.spec_all=spec_all;
            app.spec=app.spec_all(:,app.cRep);
            app.repetitionsSpinner.set('Limits',[1 max(2,size(spec_all,2))])
            app.phase_all=zeros(2,size(spec_all,2));
            app.phase_all(:,1)=phase;

            
            app.spec_corr=app.spec;
            app.Phase0radSlider.set('Limits',[-pi,pi]+phase(1));
            app.Phase0radSlider.set('Value',phase(1));
            app.Phase0Spinner.set('Value',phase(1));
            
            app.Phase1sSlider.set('Limits',[-1e3,1e3]+phase(2));
            app.Phase1sSlider.set('Value',phase(2));
            app.Phase1Spinner.set('Value',phase(2));

            app.performPhaseCorr();
            app.plotSpec();
        end

        % Value changed function: Phase0radSlider
        function Phase0radSliderValueChanged(app, event)
            value = app.Phase0radSlider.Value;
            app.Phase0Spinner.set('Value',value);
            app.performPhaseCorr();
            app.plotSpec();
           
        end

        % Value changed function: Phase1sSlider
        function Phase1sSliderValueChanged(app, event)
            value = app.Phase1sSlider.Value;
            app.Phase1Spinner.set('Value',value);
             app.performPhaseCorr();
            app.plotSpec();
        end

        % Value changed function: Phase0Spinner
        function Phase0SpinnerValueChanged(app, event)
            value = app.Phase0Spinner.Value;
            if(value<app.Phase0radSlider.Limits(1) || value>app.Phase0radSlider.Limits(2)) 
                 Limits=[-3.14,3.14]+value;
                app.Phase0radSlider.set('Limits',Limits)
            end
            app.Phase0radSlider.set('Value',value);
            app.performPhaseCorr();
            app.plotSpec();
        end

        % Value changing function: Phase1sSlider
        function Phase1sSliderValueChanging(app, event)
            value = event.Value;
            
            app.Phase1Spinner.set('Value',value);
            app.performPhaseCorr();
            app.plotSpec();
            
        end

        % Value changing function: Phase0radSlider
        function Phase0radSliderValueChanging(app, event)
            value = event.Value;
            app.Phase0Spinner.set('Value',value);
            app.performPhaseCorr();
            app.plotSpec();
            
        end

        % Value changed function: Phase1Spinner
        function Phase1SpinnerValueChanged(app, event)
            value = app.Phase1Spinner.Value;
              if(value<app.Phase1sSlider.Limits(1) || value>app.Phase1sSlider.Limits(2))
                 
                 Limits=[-1e3,1e3]+value;
          
                app.Phase1sSlider.set('Limits',Limits)
              end
           app.Phase1sSlider.set('Value',value);
            app.performPhaseCorr();
            app.plotSpec();
        end

        % Value changed function: PlotBothFlag
        function PlotBothFlagValueChanged(app, event)
            app.plotSpec();
            
        end

        % Value changed function: repetitionsSpinner
        function repetitionsSpinnerValueChanged(app, event)
            app.cRep = app.repetitionsSpinner.Value;
            app.spec=app.spec_all(:,app.cRep);
            if(app.cRep==2)
                % initial all phase with first tuned phase hopefully
                app.phase_all(1,app.phase_all(1,:)==0)=app.Phase0Spinner.Value;
                app.phase_all(2,app.phase_all(2,:)==0)=app.Phase1Spinner.Value;
            elseif(app.cRep>2)
                app.phase_all(1,app.cRep-1)=app.Phase0Spinner.Value;
                app.phase_all(2,app.cRep-1)=app.Phase1Spinner.Value;
            end
            app.Phase0radSlider.set('Value',app.phase_all(1,app.cRep))
            app.Phase1sSlider.set('Value',app.phase_all(2,app.cRep))
            

            app.performPhaseCorr();
            app.plotSpec();
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;
            pos = app.UIFigure.CurrentPoint;
            hFound=[];
            hTag_all = [findobj(app.UIFigure.Children,'Type','uispinner');...
                findobj(app.UIFigure.Children,'Type','uislider')];
            for n=1:length(hTag_all)
                hTag =hTag_all(n);
                hPos = getpixelposition(hTag,true);  % recursive to find absoulute position
                if(any(hPos(3:4)<30))
                    hPos(3)=max(30,hPos(3));
                    hPos(4)=max(30,hPos(4));
                end
                if pos(1)>hPos(1) && pos(1)<hPos(1)+hPos(3) && pos(2)>hPos(2) && pos(2)<hPos(2)+hPos(4)
                    hFound = hTag;
                    break
                end
            end
            if isempty(hFound)
                return
            end
            switch hFound.Type
                case 'uispinner'
                     val=hFound.Value - verticalScrollCount*hFound.Step;
                      if val>=hFound.Limits(1) && val<=hFound.Limits(2)
                        hFound.Value = val;
                        hFound.ValueChangedFcn(app,event);
                      end
                case 'uislider'
                    step = (hFound.Limits(2)-hFound.Limits(1))/100;
                    val = hFound.Value - verticalScrollCount*step;
                    if val>=hFound.Limits(1) && val<=hFound.Limits(2)
                        hFound.Value = hFound.Value - verticalScrollCount*step;
                        hFound.ValueChangedFcn(app,event);
                    end
                otherwise
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Phase0radSliderLabel = uilabel(app.UIFigure);
            app.Phase0radSliderLabel.Text = {'Phase0'; '[rad]  '};
            app.Phase0radSlider = uislider(app.UIFigure);
            app.Phase0radSlider.Limits = [-3.14 3.14];
            app.Phase0radSlider.ValueChangedFcn = createCallbackFcn(app, @Phase0radSliderValueChanged, true);
            app.Phase0radSlider.ValueChangingFcn = createCallbackFcn(app, @Phase0radSliderValueChanging, true);
            app.Phase0Spinner = uispinner(app.UIFigure);
            app.Phase0Spinner.Step = 0.1;
            app.Phase0Spinner.ValueChangedFcn = createCallbackFcn(app, @Phase0SpinnerValueChanged, true);
            app.Phase0Spinner.Tag = 'Scrollable';
            app.Phase1Spinner = uispinner(app.UIFigure);
            app.Phase1Spinner.Step = 10;
            app.Phase1Spinner.ValueChangedFcn = createCallbackFcn(app, @Phase1SpinnerValueChanged, true);
            app.Phase1Spinner.Tag = 'Scrollable';
            app.Phase1Spinner.Tooltip = {'Type values outside to set that as midpoint.'};
            app.Phase1Label = uilabel(app.UIFigure);
            app.Phase1Label.Text = {'Phase1 '; '[μs]   '};
            app.Phase1sSlider = uislider(app.UIFigure);
            app.Phase1sSlider.Limits = [-1000 1000];
            app.Phase1sSlider.ValueChangedFcn = createCallbackFcn(app, @Phase1sSliderValueChanged, true);
            app.Phase1sSlider.ValueChangingFcn = createCallbackFcn(app, @Phase1sSliderValueChanging, true);
            app.PlotBothFlag = uiswitch(app.UIFigure, 'slider');
            app.PlotBothFlag.Items = {'OnlyCorr', 'Both'};
            app.PlotBothFlag.ItemsData = [0 1];
            app.PlotBothFlag.ValueChangedFcn = createCallbackFcn(app, @PlotBothFlagValueChanged, true);
            app.PlotBothFlag.Value = 0;
            app.repetitionsSpinnerLabel = uilabel(app.UIFigure);
            app.repetitionsSpinnerLabel.Text = 'repetitions';
            app.repetitionsSpinner = uispinner(app.UIFigure);
            app.repetitionsSpinner.Limits = [1 Inf];
            app.repetitionsSpinner.RoundFractionalValues = 'on';
            app.repetitionsSpinner.ValueChangedFcn = createCallbackFcn(app, @repetitionsSpinnerValueChanged, true);
            app.repetitionsSpinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InteractivePhaseCorr(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end